classdef InterfazApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CHATPanel                   matlab.ui.container.Panel
        SALIRButton                 matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        ConfiguraTab                matlab.ui.container.Tab
        DESCONECTARButton           matlab.ui.control.Button
        CONECTARButton              matlab.ui.control.Button
        VelTrasmisinDropDown        matlab.ui.control.DropDown
        VelTrasmisinDropDownLabel   matlab.ui.control.Label
        PuertoDropDown              matlab.ui.control.DropDown
        PuertoDropDownLabel         matlab.ui.control.Label
        ChateaTab                   matlab.ui.container.Tab
        ENVIARButton                matlab.ui.control.Button
        EstadoLamp                  matlab.ui.control.Lamp
        EstadoLampLabel             matlab.ui.control.Label
        Enviar                      matlab.ui.control.EditField
        DatoaenviarEditFieldLabel   matlab.ui.control.Label
        Recibir                     matlab.ui.control.EditField
        DatorecibidoEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;  
        end

        % Button pushed function: SALIRButton
        function SALIRButtonPushed(app, event)
            opc=questdlg('¿Desea abandonar la conversación?','Salir','Si','No','No');
            if strcmp(opc,'Si')
                close('C:\Users\angel\Desktop\LabChat\InterfazApp.mlapp')
            end 
        end

        % Button pushed function: CONECTARButton
        function CONECTARButtonPushed(app, event)
            seriaal=app.PuertoDropDown.Value;
            vel=app.VelTrasmisinDropDown.Value;
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% DEFINIR PUERTO SERIAL A USAR %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            if (seriaal==1)
                delete(instrfind({'port'},{'COM1'}));
                app.ss=serial('COM1');
            end
            if (seriaal==2)
                delete(instrfind({'port'},{'COM2'}));
                app.ss=serial('COM2');
            end
            if (seriaal==3)
                delete(instrfind({'port'},{'COM3'}));
                app.ss=serial('COM3');
            end         
            if (seriaal==4)
                delete(instrfind({'port'},{'COM4'}));
                app.ss=serial('COM4');
            end
            if (seriaal==8)
                delete(instrfind({'port'},{'COM8'}));
                app.ss=serial('COM8');
            end
            if (seriaal==12)
                delete(instrfind({'port'},{'COM12'}));
                app.ss=serial('COM12');
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
 
            
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% DEFINIR VELOCIDAD PUERTO SERIAL %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            

            if (vel==1200)
               app.ss.BaudRate=1200;
            end
            if (vel==2400)
               app.ss.BaudRate=2400;
            end
            if (vel==4800)
               app.ss.BaudRate=4800;
            end
            if (vel==9600)
               app.ss.BaudRate=9600;
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            

            fopen(app.ss);
            global lee;
            lee=1;
            
            while (lee==1) 
                app.Recibir.Value=fscanf(app.ss);
            end
   
        end

        % Button pushed function: DESCONECTARButton
        function DESCONECTARButtonPushed(app, event)
            seriaal=app.PuertoDropDown.Value;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% DEFINIR PUERTO SERIAL A USAR %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
            if (seriaal==1)
                delete(instrfind({'port'},{'COM1'}));
            end
            if (seriaal==2)
                delete(instrfind({'port'},{'COM2'}));
            end
            if (seriaal==3)
                delete(instrfind({'port'},{'COM3'}));
            end         
            if (seriaal==4)
                delete(instrfind({'port'},{'COM4'}));
            end
            if (seriaal==8)
                delete(instrfind({'port'},{'COM8'}));
            end
            if (seriaal==12)
                delete(instrfind('Port','COM14'));
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%            
 
            global lee;
            lee=0;

        end

        % Button pushed function: ENVIARButton
        function ENVIARButtonPushed(app, event)
           fprintf(app.puerto,app.Enviar.value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CHATPanel = uipanel(app.UIFigure);
            app.CHATPanel.Title = 'CHAT';
            app.TabGroup = uitabgroup(app.CHATPanel);
            app.ConfiguraTab = uitab(app.TabGroup);
            app.ConfiguraTab.Title = 'Configura';
            app.PuertoDropDownLabel = uilabel(app.ConfiguraTab);
            app.PuertoDropDownLabel.Text = 'Puerto';
            app.PuertoDropDown = uidropdown(app.ConfiguraTab);
            app.PuertoDropDown.Items = {'COM 1', 'COM 2', 'COM 3', 'COM 4', 'COM 8', 'COM 12'};
            app.PuertoDropDown.ItemsData = {'1', '2', '3', '4', '8', '12'};
            app.PuertoDropDown.Value = '3';
            app.VelTrasmisinDropDownLabel = uilabel(app.ConfiguraTab);
            app.VelTrasmisinDropDownLabel.Text = 'Vel. Trasmisión';
            app.VelTrasmisinDropDown = uidropdown(app.ConfiguraTab);
            app.VelTrasmisinDropDown.Items = {'1200', '2400', '4800', '9600'};
            app.VelTrasmisinDropDown.ItemsData = {'1200', '2400', '4800', '9600'};
            app.VelTrasmisinDropDown.Value = '9600';
            app.CONECTARButton = uibutton(app.ConfiguraTab, 'push');
            app.CONECTARButton.ButtonPushedFcn = createCallbackFcn(app, @CONECTARButtonPushed, true);
            app.CONECTARButton.Text = 'CONECTAR';
            app.DESCONECTARButton = uibutton(app.ConfiguraTab, 'push');
            app.DESCONECTARButton.ButtonPushedFcn = createCallbackFcn(app, @DESCONECTARButtonPushed, true);
            app.DESCONECTARButton.Text = 'DESCONECTAR';
            app.ChateaTab = uitab(app.TabGroup);
            app.ChateaTab.Title = 'Chatea';
            app.DatorecibidoEditFieldLabel = uilabel(app.ChateaTab);
            app.DatorecibidoEditFieldLabel.Text = 'Dato recibido: ';
            app.Recibir = uieditfield(app.ChateaTab, 'text');
            app.Recibir.Editable = 'off';
            app.DatoaenviarEditFieldLabel = uilabel(app.ChateaTab);
            app.DatoaenviarEditFieldLabel.Text = 'Dato a enviar: ';
            app.Enviar = uieditfield(app.ChateaTab, 'text');
            app.EstadoLampLabel = uilabel(app.ChateaTab);
            app.EstadoLampLabel.Text = 'Estado';
            app.EstadoLamp = uilamp(app.ChateaTab);
            app.ENVIARButton = uibutton(app.ChateaTab, 'push');
            app.ENVIARButton.ButtonPushedFcn = createCallbackFcn(app, @ENVIARButtonPushed, true);
            app.ENVIARButton.Text = 'ENVIAR';
            app.SALIRButton = uibutton(app.CHATPanel, 'push');
            app.SALIRButton.ButtonPushedFcn = createCallbackFcn(app, @SALIRButtonPushed, true);
            app.SALIRButton.Text = 'SALIR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfazApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end