classdef lab_9 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SentidodegiroLabel           matlab.ui.control.Label
        IzquierdaButton              matlab.ui.control.Button
        DerechaButton                matlab.ui.control.Button
        PulsoSlider                  matlab.ui.control.Slider
        PulsoSliderLabel             matlab.ui.control.Label
        ManualButton                 matlab.ui.control.Button
        VirtualButton                matlab.ui.control.Button
        MododegiroLabel              matlab.ui.control.Label
        CONTROLADORREMOTOMOTORLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        s % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.s = serialport('COM3',9600,'DataBits',8,"Timeout",100);
            pause(0.1);

        end

        % Button pushed function: VirtualButton
        function VirtualButtonPushed(app, event)
            global mode_virtual;
            mode_virtual = 'V';
            write(app.s,mode_virtual,'char');
            pause(0.1);
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
             global modemanual;
            mode_manual = 'M';
            write(app.s,mode_manual,'char');
            pause(0.1);
        end

        % Button pushed function: DerechaButton
        function DerechaButtonPushed(app, event)
             global sentido_horario;
            sentido_horario = 'D';
            write(app.s,sentido_horario,'char');
            pause(0.1);
            
        end

        % Button pushed function: IzquierdaButton
        function IzquierdaButtonPushed(app, event)
              global sentido_antihorario;
            sentido_antihorario = 'I';
            write(app.s,sentido_antihorario,'char');
            pause(0.1);
               
        end

        % Value changed function: PulsoSlider
        function PulsoSliderValueChanged(app, event)
            value = app.PulsoSlider.Value;
            write(app.s,value+48,'char')
        
            if (value> 0) && (value<= 4) 
           
            elseif (value>4) && (value<= 7) 
             
            
           elseif (value>7)        
              
            elseif(value == 0)
    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CONTROLADORREMOTOMOTORLabel = uilabel(app.UIFigure);
            app.CONTROLADORREMOTOMOTORLabel.Text = 'CONTROLADOR REMOTO MOTOR';
            app.MododegiroLabel = uilabel(app.UIFigure);
            app.MododegiroLabel.Text = 'Modo de giro: ';
            app.VirtualButton = uibutton(app.UIFigure, 'push');
            app.VirtualButton.ButtonPushedFcn = createCallbackFcn(app, @VirtualButtonPushed, true);
            app.VirtualButton.Text = 'Virtual';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.PulsoSliderLabel = uilabel(app.UIFigure);
            app.PulsoSliderLabel.Text = 'Pulso';
            app.PulsoSlider = uislider(app.UIFigure);
            app.PulsoSlider.Limits = [0 10];
            app.PulsoSlider.ValueChangedFcn = createCallbackFcn(app, @PulsoSliderValueChanged, true);
            app.DerechaButton = uibutton(app.UIFigure, 'push');
            app.DerechaButton.ButtonPushedFcn = createCallbackFcn(app, @DerechaButtonPushed, true);
            app.DerechaButton.Text = 'Derecha';
            app.IzquierdaButton = uibutton(app.UIFigure, 'push');
            app.IzquierdaButton.ButtonPushedFcn = createCallbackFcn(app, @IzquierdaButtonPushed, true);
            app.IzquierdaButton.Text = 'Izquierda';
            app.SentidodegiroLabel = uilabel(app.UIFigure);
            app.SentidodegiroLabel.Text = 'Sentido de giro:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab_9

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end