classdef App_Taller_PWM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        SERVO2       matlab.ui.control.NumericEditField
        SERVO2Label  matlab.ui.control.Label
        SERVO1       matlab.ui.control.NumericEditField
        SERVO1Label  matlab.ui.control.Label
        STOPButton   matlab.ui.control.Button
        STARTButton  matlab.ui.control.Button
        Grafica      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            delete(instrfind({'Port'},{'COM3'}));
            device=serial('COM3','BaudRate',9600);
            fopen(device);

            i=1;
            
               hold(app.Grafica);
            while(1)
               a=fscanf(device,'%f,%f\r\n');
               app.SERVO1.Value=a(1)/100;
               app.SERVO2.Value=a(2);
               
               
               Servo1(i)=a(1)/100;
               Servo2(i)=a(2);
                  
               
                plot(app.Grafica,Servo1,'r');
                plot(app.Grafica,Servo2,'b');
                 
        
                i=i+1;
                pause(0.1);
            
            end
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
           fclose(device);
            delete(device);
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica = uiaxes(app.UIFigure);
            title(app.Grafica, 'GRAFICA DE POSICION ANGULAR EN SERVOMOTORES')
            xlabel(app.Grafica, 'MUESTRAS')
            ylabel(app.Grafica, 'ANGULO (°)')
            zlabel(app.Grafica, 'Z')
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.SERVO1Label = uilabel(app.UIFigure);
            app.SERVO1Label.Text = 'SERVO 1';
            app.SERVO1 = uieditfield(app.UIFigure, 'numeric');
            app.SERVO2Label = uilabel(app.UIFigure);
            app.SERVO2Label.Text = 'SERVO 2';
            app.SERVO2 = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Taller_PWM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end